classdef Light_Field_Generator_3D_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SpecialButton                   matlab.ui.control.StateButton
        updatetableButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BeamGenerationTable             matlab.ui.container.Tab
        SaveBeamButton                  matlab.ui.control.Button
        VariablenameforsavingbeamtoworkspaceEditField  matlab.ui.control.EditField
        VariablenameforsavingbeamtoworkspaceEditFieldLabel  matlab.ui.control.Label
        SpeedResolutionKnob             matlab.ui.control.DiscreteKnob
        SpeedResolutionKnobLabel        matlab.ui.control.Label
        DefaultInteractivePlotCheckBox  matlab.ui.control.CheckBox
        VisualisationOptionsPanel       matlab.ui.container.Panel
        BaseZIncramentsEditField        matlab.ui.control.NumericEditField
        BaseZIncramentsLabel            matlab.ui.control.Label
        ZLimitsEditField                matlab.ui.control.NumericEditField
        ZLimitsLabel                    matlab.ui.control.Label
        YLimitsEditField                matlab.ui.control.NumericEditField
        YLimitsEditFieldLabel           matlab.ui.control.Label
        BaseYIncramentsEditField        matlab.ui.control.NumericEditField
        BaseYIncramentsLabel            matlab.ui.control.Label
        BaseXIncramentsEditField        matlab.ui.control.NumericEditField
        BaseXIncramentsEditFieldLabel   matlab.ui.control.Label
        XLimitsEditField                matlab.ui.control.NumericEditField
        XLimitsEditFieldLabel           matlab.ui.control.Label
        LoadTableButton                 matlab.ui.control.Button
        LoadatablefromworkspaceDropDown  matlab.ui.control.DropDown
        LoadatablefromworkspaceDropDownLabel  matlab.ui.control.Label
        SaveTableButton                 matlab.ui.control.Button
        VariablenameforsavingtabletoworkspaceEditField  matlab.ui.control.EditField
        VariablenameforsavingtabletoworkspaceEditFieldLabel  matlab.ui.control.Label
        ActiveVisuallisationsInAllVisualisationsPanel  matlab.ui.container.Panel
        IntensityXZButton               matlab.ui.control.StateButton
        IntensityXYButton               matlab.ui.control.StateButton
        PhaseButton                     matlab.ui.control.StateButton
        SumofcomponentintensitiesButton  matlab.ui.control.StateButton
        IndividualcomponentintensitiesButton  matlab.ui.control.StateButton
        RadianceButton                  matlab.ui.control.StateButton
        Gauge                           matlab.ui.control.LinearGauge
        GenerateBeamVisualissationButton  matlab.ui.control.Button
        GenerateBeamsButton             matlab.ui.control.Button
        Gauge_2                         matlab.ui.control.LinearGauge
        TextArea                        matlab.ui.control.TextArea
        Label_2                         matlab.ui.control.Label
        BaseOptionsPanel                matlab.ui.container.Panel
        UseCapCheckBox                  matlab.ui.control.CheckBox
        NMaxCapEditField                matlab.ui.control.NumericEditField
        NMaxCapEditFieldLabel           matlab.ui.control.Label
        IndexMediumEditField            matlab.ui.control.NumericEditField
        IndexMediumEditFieldLabel       matlab.ui.control.Label
        DefaultPowerEditField           matlab.ui.control.NumericEditField
        DefaultPowerEditFieldLabel      matlab.ui.control.Label
        DefaultNAEditField              matlab.ui.control.NumericEditField
        DefaultNAEditFieldLabel         matlab.ui.control.Label
        DefaultWavelengthEditField      matlab.ui.control.NumericEditField
        DefaultWavelengthEditFieldLabel  matlab.ui.control.Label
        BeamNumberSpinner               matlab.ui.control.Spinner
        BeamNumberSpinnerLabel          matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        InteractiveFigureTab            matlab.ui.container.Tab
        DisableallrealtimeButton        matlab.ui.control.Button
        RealTimeXZUpdatingCheckBox      matlab.ui.control.CheckBox
        Gauge_4                         matlab.ui.control.LinearGauge
        Gauge_3                         matlab.ui.control.LinearGauge
        RegenerateVisualisationsButton  matlab.ui.control.Button
        RegenerateBeamsButton           matlab.ui.control.Button
        RefreshTableBasedOnMovedPositionsButton  matlab.ui.control.Button
        RealTimeXYUpdatingCheckBox      matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        YZButton                        matlab.ui.control.ToggleButton
        XZButton                        matlab.ui.control.ToggleButton
        XYButton                        matlab.ui.control.ToggleButton
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        AllVisuallisationsTab           matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
    end

    
    properties (Access = private)
        base_table_row
        temp_table
        % wavelength = 1;
        

        NA_param = 1;


    end
    
    properties (Access = public)
        param_table % Description

        netbeam;

        moveable_beams;
        
        Beams = struct;

        Points = struct;

        Display = struct;

        positions;

        droplinesx;
        droplinesy;
        droplinesz;

        xlines;
        ylines;
        zlines;

        xcalibration
        ycalibration
        zcalibration

        handles;

        Figcounter;

        Eint2;

        E;
        E2;
    
        Ei;
        Ei2;
        Ep;
        I;

        Ex;
        Ey;
        Ez;

        Exc;
        Eyc;
        Ezc;

        Eyz;
        Spec;

        img;

        xyzint2;

        nx;
        ny;
        nz;
        xrange;
        yrange;
        zrange;
        xyz;
        xyz2;

        xyz_all;

        realtime_table_i = [];
        realtime_table_f = [];
        realtime_table_c = [];

        ax;

        temp

        tiledpanel


    end
    
    methods (Access = private)
        
        function dragfig(app)   %function for allowing figure to be dragged


            %Set up basic parameters
            hold(app.UIAxes,"on")
            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.XLim = [-app.XLimitsEditField.Value app.XLimitsEditField.Value];
            app.UIAxes.YLim = [-app.YLimitsEditField.Value app.YLimitsEditField.Value];
            app.UIAxes.ZLim = [-app.ZLimitsEditField.Value app.ZLimitsEditField.Value];
            app.UIAxes.XLabel.String = 'X';
            app.UIAxes.YLabel.String = 'Y';
            app.UIAxes.ZLabel.String = 'Z';
            grid(app.UIAxes, 'on')


            %clear any previous instance
            cla(app.UIAxes)
            %Reset positions
            app.realtime_table_i = [];
            
            %Colour tracker for each point
            CData_list = zeros(height(app.param_table),3);


            for i = 1:height(app.param_table)

                %Get position of each point
                point = evalin('base', app.param_table.Offset(i));
                %Set up array for initial position of each point
                app.realtime_table_i(i,:) = [point(1);point(2);point(3)];
                %Set up a new scatter plot for each point and assign to
                %handle inside a struct
                app.Points(i).a = scatter3(app.UIAxes,point(1),point(2),point(3),'DisplayName',"Beam "+i, UserData=i)';
                %Collect colour of each generated point 
                CData_list(i,:) = get(app.Points(i).a, 'CData');
                %Make each scatter draggable in window 
                draggable_3d(app.Points(i).a, [-app.XLimitsEditField.Value, app.XLimitsEditField.Value, -app.YLimitsEditField.Value, app.YLimitsEditField.Value, -app.ZLimitsEditField.Value, app.ZLimitsEditField.Value], @(varargin)Dragged(app,app.Points(i).a) )

            end

            legend(app.UIAxes);
            
            %Set of buttons to reorient camera along each axis to make
            %dragged points easier to move
            if app.XYButton.Value
                app.UIAxes.View = [0,90];
            
            elseif app.XZButton.Value
                app.UIAxes.View = [0,0];

            elseif app.YZButton.Value
                app.UIAxes.View = [90,0];
            end

            hold(app.UIAxes,"off")


            clear app.positions(i)
            cla(app.UIAxes_2)


            %Set up points in 3d space representing beam centre positions
            hold(app.UIAxes_2,"on")

            app.positions = scatter3(app.UIAxes_2,app.realtime_table_i(:,1),app.realtime_table_i(:,2),app.realtime_table_i(:,3),30,CData_list,"filled");
            
            app.xlines = [app.XLimitsEditField.Value.*ones(height(app.param_table),1), app.realtime_table_i(:,1)].';
            app.ylines = [app.YLimitsEditField.Value.*ones(height(app.param_table),1), app.realtime_table_i(:,2)].';
            app.zlines = [-app.ZLimitsEditField.Value.*ones(height(app.param_table),1), app.realtime_table_i(:,3)].';

            app.xcalibration = [app.realtime_table_i(:,1), app.realtime_table_i(:,1)].';
            app.ycalibration = [app.realtime_table_i(:,2), app.realtime_table_i(:,2)].';
            app.zcalibration = [app.realtime_table_i(:,3), app.realtime_table_i(:,3)].';

            %Lines connecting points to grid for easier visualisation 
            app.droplinesx =  plot3(app.UIAxes_2,app.xlines, app.ycalibration, app.zcalibration,'r');
            app.droplinesy =  plot3(app.UIAxes_2,app.xcalibration, app.ylines, app.zcalibration,'g');
            app.droplinesz =  plot3(app.UIAxes_2,app.xcalibration, app.ycalibration, app.zlines,'b');

            app.UIAxes_2.XLim = [-app.XLimitsEditField.Value app.XLimitsEditField.Value];
            app.UIAxes_2.YLim = [-app.YLimitsEditField.Value app.YLimitsEditField.Value];
            app.UIAxes_2.ZLim = [-app.ZLimitsEditField.Value app.ZLimitsEditField.Value];
            app.UIAxes_2.XLabel.String = 'X';
            app.UIAxes_2.YLabel.String = 'Y';
            app.UIAxes_2.ZLabel.String = 'Z';
            grid(app.UIAxes_2, 'on')

            view(app.UIAxes_2, 3)
            hold(app.UIAxes_2,"off")

        end


        function Dragged(app, h)
        %Function called when a point is moved

        %Clear relevant variables and figures 
        % clear  netbeam Ei Ep I E H app.netbeam app.img  
        cla(app.UIAxes_3, 'reset')

        %Select scatter plot index that is being moved 
        i = get(h, 'UserData');


        %Compare current position

        temp = [0;0;0];
        %Set up array for new moved position
        app.realtime_table_f = [get(app.Points(i).a, 'XData'); get(app.Points(i).a, 'YData'); get(app.Points(i).a, 'ZData')];
        %Set up array for change in position from initial position to new
        %position 
        app.realtime_table_c = app.realtime_table_f - app.realtime_table_i(i,:)';
        % Add new transfromation to temporary array 
        temp = temp + app.realtime_table_c;


        %Update XY plane representation if chackbox ticked 
        if app.RealTimeXYUpdatingCheckBox.Value
            %Update XY plane of selected scatter field by offsetting in
            %reverse to the transform of the particle
            % % % app.E(:,:,i) = app.moveable_beams(i).emFieldXyz(app.xyz.' - temp);
            app.E(:,:,i) = app.moveable_beams(i).emFieldRtp(ott.utils.xyz2rtp((app.xyz.' - temp).').');
            

            %Set arbitrary value based on sum of electric field components
            %across the grid and then reasemble whole image 
            % arb = sum(app.E,3).';
            % % % % datai = app.netbeam.GetVisualisationData('E2', app.xyz, [], arb', []);
            % datai = sum(abs(arb).^2, 2);
            % app.Ei = reshape(datai, app.ny, app.nx);

            % app.Ei = reshape(sum(abs(sum(app.E,3).').^2, 2), app.ny, app.nx);

            app.Ei = reshape(sum(abs(sum(app.E,3).').^2, 2), app.ny, app.nx);


            %Display image and fill out details 
            imagesc(app.UIAxes_3, app.xrange, app.yrange, (app.Ei));
            axis(app.UIAxes_3, 'image');
            app.UIAxes_3.YDir = 'normal';
            title(app.UIAxes_3,'E field intensity');
            xlabel(app.UIAxes_3,'X [\lambda_0]'); ylabel(app.UIAxes_3,'Y [\lambda_0]');
        end

        % Repeat above steps for Z-Y image
        if app.RealTimeXZUpdatingCheckBox.Value

                % Calculate the intensity of the E field
                % app.E2(:,:,i) = app.moveable_beams(i).emFieldXyz(app.xyz2.' - temp);
                % arb2 = sum(app.E2,3);
                % datai2 = app.netbeam.GetVisualisationData('E2', app.xyz2, [], arb2', []);
                % app.Ei2 = reshape(datai2, app.nz, app.nx);

                app.E2(:,:,i) = app.moveable_beams(i).emFieldRtp(ott.utils.xyz2rtp((app.xyz2.' - temp).').');
                % app.E2(:,:,i) = app.moveable_beams(i).emFieldXyz(app.xyz2.' - temp);
                app.Ei2 = reshape((sum(abs((sum(app.E2,3)).').^2, 2)), app.nz, app.nx);

                imagesc(app.UIAxes_7, app.xrange, app.zrange, (app.Ei2));
                % axis(app.UIAxes_7,'square');
                axis(app.UIAxes_7, 'image');
                % xlim(app.UIAxes_7, [-app.XLimitsEditField.Value, app.XLimitsEditField.Value]);
                % ylim(app.UIAxes_7, [-app.ZLimitsEditField.Value, app.ZLimitsEditField.Value]);
                app.UIAxes_7.YDir = 'normal';
                xlabel(app.UIAxes_7,'X [\lambda_0]'); ylabel(app.UIAxes_7,'Z [\lambda_0]');
   
        end


        %%%Update position grid of points in 3d space 
        app.positions.XData(i) = app.realtime_table_f(1);
        app.positions.YData(i) = app.realtime_table_f(2);
        app.positions.ZData(i) = app.realtime_table_f(3);
        
        %Update droplines for each
        app.droplinesx(i).XData =  [app.XLimitsEditField.Value, app.realtime_table_f(1)].';
        app.droplinesx(i).YData =  [app.realtime_table_f(2), app.realtime_table_f(2)].';
        app.droplinesx(i).ZData =  [app.realtime_table_f(3), app.realtime_table_f(3)].';
        
        app.droplinesy(i).XData =  [app.realtime_table_f(1), app.realtime_table_f(1)].';
        app.droplinesy(i).YData =  [app.YLimitsEditField.Value, app.realtime_table_f(2)].';
        app.droplinesy(i).ZData =  [app.realtime_table_f(3), app.realtime_table_f(3)].';
        
        app.droplinesz(i).XData =  [app.realtime_table_f(1), app.realtime_table_f(1)].';
        app.droplinesz(i).YData =  [app.realtime_table_f(2), app.realtime_table_f(2)].';
        app.droplinesz(i).ZData =  [-app.ZLimitsEditField.Value, app.realtime_table_f(3)].';


        end
        

        %Initialisation of figures for real time updating when dragging
        %points 
        function Initialize_drag_fig(app)

            %Clear relevant variables and figures 
            clear  netbeam Ei Ep I E H app.netbeam app.img app.Ei app.Ei2 app.E app.E2       
            cla(app.UIAxes_3, 'reset')
    
            title(app.UIAxes_3,'E field intensity X-Y');
    
            %calc vals for both
            if or(app.RealTimeXYUpdatingCheckBox.Value, app.RealTimeXZUpdatingCheckBox.Value)
                %Set empty variables and then assign beams to it 
                app.moveable_beams = [];
                app.moveable_beams = [app.Beams(:).a];    
    
            end

            %Allow updating of XY table 
            if app.RealTimeXYUpdatingCheckBox.Value == 1
    
                %Recalculate grid electric field upon moving the beams 
                app.E = [];
                for i = 1:length(app.moveable_beams)
                    app.moveable_beams(i).basis = 'regular';
                    app.E(:,:,i) = app.moveable_beams(i).emFieldXyz(app.xyz.');    

                end
                
                % Create E field fig for XY 
                % arb = sum(app.E,3);
                % Ei=reshape(sqrt(sum(real(arb).^2,1)),[app.nx,app.ny]);

                arb = sum(app.E,3);
                datai = app.netbeam.GetVisualisationData('E2', app.xyz, [], arb', []);
                app.Ei = zeros(app.ny, app.nx, length(app.moveable_beams));
                app.Ei = reshape(datai, app.ny, app.nx);

                imagesc(app.UIAxes_3, app.xrange, app.yrange, (app.Ei));
                axis(app.UIAxes_3,'square');
                app.UIAxes_3.YDir = 'normal';
                title(app.UIAxes_3,'E field intensity');
                xlabel(app.UIAxes_3,'X [\lambda_0]'); ylabel(app.UIAxes_3,'Y [\lambda_0]');
            end

            % Create E field fig for ZY plane if requested  
            title(app.UIAxes_7,'E field intensity Z-Y');
    
            if app.RealTimeXZUpdatingCheckBox.Value == 1
                app.E2 = [];

    
                for i = 1:length(app.moveable_beams)
                    app.E2(:,:,i) = app.moveable_beams(i).emFieldXyz(app.xyz2.');
                end
    
                % Calculate the intensity of the E field
                % arb2 = sum(app.E2,3);
                % datai2 = app.netbeam.GetVisualisationData('E2', app.xyz2, [], arb2.', []);
                % app.Ei2 = zeros(app.nz, app.nx, length(app.moveable_beams));
                % app.Ei2 = reshape(datai2, app.nz, app.nx);
                % app.Ei2=reshape(sqrt(sum(real(arb2).^2,1)),[app.nx,app.nz]);
    
                imagesc(app.UIAxes_7, app.xrange, app.zrange, (app.Ei2));
                axis(app.UIAxes_7,'square');
                xlim(app.UIAxes_7, [-app.XLimitsEditField.Value, app.XLimitsEditField.Value]);
                ylim(app.UIAxes_7, [-app.ZLimitsEditField.Value, app.ZLimitsEditField.Value]);
                app.UIAxes_7.YDir = 'normal';
                xlabel(app.UIAxes_7,'X [\lambda_0]'); ylabel(app.UIAxes_7,'Z [\lambda_0]');


            end
        end
        
        % Refresh drop down menu for beam paramater table   
        function RefreshDropDownLists(app, clear_value)

            if nargin < 2
                clear_value = false;
            end
            
            % Refresh the drop down menus with any tables in the work space
            ott.ui.support.populateVariableList(app.LoadatablefromworkspaceDropDown, 'table');

            % Present nothing if no values
            if clear_value
                app.BeamDropDown.Value = '';
            end
            
        end
        
        %Generate all selected visualisations in tab 3 
        function VisTiles(app)
            %Get list of visualisations that have been requested 
            app.temp = allchild(app.ActiveVisuallisationsInAllVisualisationsPanel);
            app.temp = get(app.temp, 'Value');
            %Sum number of requested visuallisations 
            app.Figcounter = sum(cell2mat(app.temp));

            % If each indidividual x,y,z component is requested add 2 to
            % sum for extra 2 visualisations
            if app.IndividualcomponentintensitiesButton.Value
            app.Figcounter = app.Figcounter + 2;
            end

            % Select configuration grid of visualisations to optomize for
            % number of total visualisations 
            if app.Figcounter == 1
                tilerows = 1;
                tilecols = 1;
            elseif app.Figcounter == 2
                tilerows = 1;
                tilecols = 2;
            elseif and(app.Figcounter >2, app.Figcounter <5)
                tilerows = 2;
                tilecols = 2;
            elseif and(app.Figcounter >4, app.Figcounter <7)
                tilerows = 2;
                tilecols = 3;
            elseif and(app.Figcounter >6, app.Figcounter <10)
                tilerows = 3;
                tilecols = 3;
            else 
                tilerows = 0;
                tilecols = 0;
            end
            
            %Set up tiled panel 
            app.tiledpanel = tiledlayout(app.Panel,tilerows,tilecols);
            

            %Generate each visualisation if requested 

            %Generate XY plane intensity 
            if app.IntensityXYButton.Value
                app.ax = nexttile(app.tiledpanel);
                imagesc(app.ax, app.xrange, app.yrange, (app.Ei));
                axis(app.ax,'square');
                app.ax.YDir = 'normal';
                title(app.ax,'E field intensity');
                xlabel(app.ax,'X [\lambda_0]'); ylabel(app.ax,'Y [\lambda_0]');
            end

            %Generate XZ plane intensity 
            if app.IntensityXZButton.Value
                app.ax = nexttile(app.tiledpanel);
                imagesc(app.ax, app.xrange, app.zrange, app.Ei2)
                app.ax.YDir = 'normal';
                axis(app.ax,'square');
                title(app.ax,'E field intensity X-Z plane');
                xlabel(app.ax,'X [\lambda_0]'); ylabel(app.ax,'Z [\lambda_0]');

            end

            %Generate XY phase plot 
            if app.PhaseButton.Value
                app.ax = nexttile(app.tiledpanel);
                imagesc(app.ax, app.xrange, app.yrange, app.Ep);
                axis(app.ax,'square');
                app.ax.YDir = 'normal';
                title(app.ax,'E field phase');
                xlabel(app.ax,'X [\lambda_0]'); ylabel(app.ax,'Y [\lambda_0]');
            end

            %Generate XY Radiance plot 
            if app.RadianceButton.Value
                app.ax = nexttile(app.tiledpanel);
                imagesc(app.ax, app.xrange, app.yrange, app.I);
                axis(app.ax,'square');
                app.ax.YDir = 'normal';
                title(app.ax,'Radiance');
                xlabel(app.ax,'X [\lambda_0]'); ylabel(app.ax,'Y [\lambda_0]');
            end

            %Generate sum of XY intensities as sum of each individual colour component 
            if app.SumofcomponentintensitiesButton.Value
                app.ax = nexttile(app.tiledpanel);
                imagesc(app.ax, app.xrange, app.yrange, app.Exc+app.Eyc+app.Ezc);
                axis(app.ax,'square');
                app.ax.YDir = 'normal';
                title(app.ax,'Sum component intensity');
                xlabel(app.ax,'X [\lambda_0]'); ylabel(app.ax,'Y [\lambda_0]');
            end

            %Generate XY intensities as each individual colour component 
            if app.IndividualcomponentintensitiesButton.Value
                app.ax = nexttile(app.tiledpanel);
                imagesc(app.ax, app.xrange, app.yrange, app.Exc);
                axis(app.ax,'square');
                app.ax.YDir = 'normal';
                title(app.ax,'X component of intensity');
                xlabel(app.ax,'X [\lambda_0]'); ylabel(app.ax,'Y [\lambda_0]');

                app.ax = nexttile(app.tiledpanel);
                imagesc(app.ax, app.xrange, app.yrange, app.Eyc);
                axis(app.ax,'square');
                app.ax.YDir = 'normal';
                title(app.ax,'Y component of intensity');
                xlabel(app.ax,'X [\lambda_0]'); ylabel(app.ax,'Y [\lambda_0]');

                app.ax = nexttile(app.tiledpanel);
                imagesc(app.ax, app.xrange, app.yrange, app.Ezc);
                axis(app.ax,'square');
                app.ax.YDir = 'normal';
                title(app.ax,'Z component of intensity');
                xlabel(app.ax,'X [\lambda_0]'); ylabel(app.ax,'Y [\lambda_0]');
            end

            %Generate attempt at XY polarisation plot 
            if app.SpecialButton.Value
                app.ax = nexttile(app.tiledpanel);
                imagesc(app.ax, app.xrange, app.yrange, app.Spec);
                axis(app.ax,'square');
                app.ax.YDir = 'normal';
                title(app.ax,'Special');
                xlabel(app.ax,'X [\lambda_0]'); ylabel(app.ax,'Y [\lambda_0]');
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function TableStrt(app)
            %Generate table for the creation of beams

            %Set up categrocial for beam types 
            type_categorical = categorical({'Gaussian','lg','hg','ig'});

            %Set up Column names and variables for the table 
            Column_Names = {'Beam','Type','Mode','Offset','Polarisation','Power','Wavelength', 'NA', 'Phase', 'Clone Row','Delete Row'};
            Column_Variable_Types = {'int8','categorical','string', 'string', 'string','double', 'double',  'double', 'string', 'logical', 'logical'};
            
            %Set up table
            app.param_table = table('Size',[1 11],'VariableTypes',Column_Variable_Types,'VariableNames',Column_Names);

            %List of initial default paramaters
            app.param_table.Beam = 1;
            app.param_table.Type = type_categorical(2);
            app.param_table.Mode = "[0, 0]";
            app.param_table.Polarisation = "[1, i]";
            app.param_table.Power = app.DefaultPowerEditField.Value;   
            app.param_table.Offset = "[0;0;0]";
            % app.param_table.Angle = "eye(3)";
            app.param_table.Wavelength = app.DefaultWavelengthEditField.Value;
            app.param_table.NA = app.DefaultNAEditField.Value;
            app.param_table.Phase = "1+0*i";

            %Create default row for generation of extra rows
            app.base_table_row={2,categorical({'lg'}),"[0, 0]","[0;0;0]","[1, i]",app.DefaultPowerEditField.Value,app.DefaultWavelengthEditField.Value,app.DefaultNAEditField.Value,"1+0*i", false, false};

            %Display table in app
            app.UITable.Data = app.param_table;

            %Make sure app does not appear outside bounds of screen window 
            movegui(app.UIFigure,'center')      

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %Implement any changes in table variable
            indices = event.Indices;
            newData = event.NewData;
            app.param_table(indices(1),indices(2)) = {newData};

            % Make sure mode options is correct for type selected
            if iscategorical(newData)
                if newData == "ig" && indices(2) == find(string(app.param_table.Properties.VariableNames) == "Type")
                    app.param_table(indices(1),find(string(app.param_table.Properties.VariableNames) == "Mode")) = {"[0,0,0,0]"};
                end
                if newData == "lg" && indices(2) == find(string(app.param_table.Properties.VariableNames) == "Type")
                    app.param_table(indices(1),find(string(app.param_table.Properties.VariableNames) == "Mode")) = {"[0,0]"};
                end                
                if newData == "hg" && indices(2) == find(string(app.param_table.Properties.VariableNames) == "Type")
                    app.param_table(indices(1),find(string(app.param_table.Properties.VariableNames) == "Mode")) = {"[0,0]"};
                end
                if newData == "Gaussian" && indices(2) == find(string(app.param_table.Properties.VariableNames) == "Type")
                    app.param_table(indices(1),find(string(app.param_table.Properties.VariableNames) == "Mode")) = {""};
                end
            end


            %One Clone or delete row selected 
            if islogical(newData) 
                %If a row is deleted and isn't the last available row:
                if newData == true && indices(2) == find(string(app.param_table.Properties.VariableNames) == "Delete Row") && height(app.param_table) > 1
                    %Erase selected row 
                    app.param_table(indices(1),:) = [];
                    %Reduce spinner value by 1  
                    app.BeamNumberSpinner.Value = app.BeamNumberSpinner.Value - 1;
                    
                    %Reassign row numbers based on remaining beams   
                    if height(app.param_table) >= indices(1)
                        for i = indices(1):height(app.param_table)
                            app.param_table(i, find(string(app.param_table.Properties.VariableNames) == "Beam")) = {i};
                        end
                    end

                %Clone row selected    
                elseif newData == true && indices(2) == find(string(app.param_table.Properties.VariableNames) == "Clone Row")
                    %Uncheck box to clone row    
                    app.param_table(indices(1),indices(2)) = {false};
                    %Clone row    
                    app.param_table = [app.param_table;app.param_table(indices(1),:)];
                    %Assign appropriate table beam number    
                    app.param_table(height(app.param_table), find(string(app.param_table.Properties.VariableNames) == "Beam")) = {height(app.param_table)};
                    %Assign appropriate beam number spinner value    
                    app.BeamNumberSpinner.Value = app.BeamNumberSpinner.Value + 1;
                else
                    %Tried to delete only available final row, do nothing     
                    app.param_table(indices(1),indices(2)) = {false};
                end 
            end            

           % update table to app 
           app.UITable.Data = app.param_table;

        end

        % Value changed function: BeamNumberSpinner
        function BeamNumberSpinnerValueChanged(app, event)
            % Assign variable for spinner value number  
            beam_quant = app.BeamNumberSpinner.Value;
            % If beam number is greater than current number of beams on
            % table then generate sufficient number of default additional
            % rows and assign to table 
            if beam_quant > height(app.param_table)
                temp = height(app.param_table);
                for i = 1 : (beam_quant - height(app.param_table))
                    app.base_table_row{1} = temp + i;
                    app.param_table = [app.param_table;app.base_table_row];
                end
            end
            % If beam number is lesser than current number of beams on
            % table then delete sufficient number rows from table 
            if beam_quant < height(app.param_table)
                for i = 1 : (height(app.param_table)-beam_quant)
                    app.param_table(end,:) = [];
                end 
            end

            % update table 
            app.UITable.Data= app.param_table;

        end

        % Button pushed function: GenerateBeamsButton
        function GenerateBeamsButtonPushed(app, event)
            
            %clear any previous generated beams and assign initial value to
            %beam generation progress guages of 1/100
            app.Beams = [];
            app.Gauge.Value = 1;
            app.Gauge_3.Value = 1;

            %assign universl nmax value as either capped or null based on
            %inputs
           if app.UseCapCheckBox.Value
               Nmax = app.NMaxCapEditField.Value;
           else
               Nmax = []
           end

            %for each beam row in the table:
            for i = 1:height(app.param_table)

                % Get parameters from GUI
                type = char(app.param_table.Type(i));
                if not(strcmp('Gaussian', type))
                    mode = evalin('base', app.param_table.Mode(i));
                end
                polarisation = evalin('base', app.param_table.Polarisation(i));
                offset = evalin('base', app.param_table.Offset(i));
                index_medium =  app.IndexMediumEditField.Value;
                NA = app.param_table.NA(i);
                wavelength = app.param_table.Wavelength(i); 
                power = app.param_table.Power(i);
                angle = evalin('base', app.param_table.Polarisation(i));

                phase = evalin('base', app.param_table.Phase(i));

            % Generate beam and add to beam structure, if gaussian no mode input is necessarym
            % otherwise provide mode
            if type == "Gaussian"
                app.Beams(i).a = ott.BscPmGauss(...
                'wavelength0', wavelength, ...
                'offset', offset, ...
                'Nmax', Nmax, ...                
                'index_medium', index_medium, ...
                'polarisation', polarisation, ... 
                'NA', NA, ...
                'power', power) * phase;
            else
                app.Beams(i).a = ott.BscPmGauss(type, mode, ...
                'wavelength0', wavelength, ...
                'offset', offset, ...
                'Nmax', Nmax, ...                
                'index_medium', index_medium, ...
                'polarisation', polarisation, ... 
                'NA', NA, ...
                'power', power) * phase;
            end
                
            %assign value to beam generation progress guages of according
            %to fraction of completed beams 
                app.Gauge.Value = (100*i./height(app.param_table));
                app.Gauge_3.Value = (100*i./height(app.param_table));

            end
           

        end

        % Value changed function: DefaultWavelengthEditField
        function DefaultWavelengthEditFieldValueChanged(app, event)
            %Assign new default value for wavelength to default row when
            %adding beams 
            app.wavelength = app.DefaultWavelengthEditField.Value;
            app.base_table_row={2,categorical({'lg'}),"[0, 0]","[1, i]",app.DefaultPowerEditField.Value,"[0;0;0]","eye(3)",app.DefaultWavelengthEditField.Value,app.DefaultNAEditField.Value,"1+0*i", false};

        end

        % Button pushed function: updatetableButton
        function updatetableButtonPushed(app, event)
            %Debug tool
            %Assign various paramaters of interest to the workspace 

            % % % assignin('base','param_table',app.param_table)
            % % % assignin('base','type', char(app.param_table.Type(1)))
            % % % assignin('base','mode',evalin('base', app.param_table.Mode(1)))
            % % % assignin('base','beams',app.Beams)
            % % % assignin('base','net_beam',app.netbeam)
            % % % assignin('base','points',app.Points)
            % % % assignin('base','Ee',app.E)
            % % % assignin('base','moveable',app.moveable_beams)
            % % % assignin('base','c',app.realtime_table_c)
            % % % assignin('base','temp',app.temp)
            % % % assignin('base','xline',app.xlines)
            % % % assignin('base','xcalibration',app.xcalibration)
            % % % assignin('base','droplinesx',app.droplinesx)
            % % % assignin('base','Eint2',app.Eint2)

        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            % Write the table to the current matlab workspace
            
            % Is there a table to write
            if isempty(app.param_table)
                return;   % Nothing to do
            end
            
            % Get the variable name
            name = app.VariablenameforsavingtabletoworkspaceEditField.Value;

            % If no name do nothing 
            if isempty(name)
                return;   
            end
            
            % Check the name is valid
            if ~isvarname(name)
                error('Invalid variable name in ''Variable Name'' field');
               
            end   
            
            % Write the table to the workspace
            assignin('base', name, app.param_table);

            
        end

        % Value changed function: DefaultPowerEditField
        function DefaultPowerEditFieldValueChanged(app, event)
            %Assign new default value for power to default row when
            %adding beams 
            value = app.DefaultPowerEditField.Value;
                app.base_table_row={2,categorical({'lg'}),"[0, 0]","[1, i]",app.DefaultPowerEditField.Value,"[0;0;0]","eye(3)",app.DefaultWavelengthEditField.Value,app.DefaultNAEditField.Value,"1+0*i", false};

        end

        % Value changed function: DefaultNAEditField
        function DefaultNAEditFieldValueChanged(app, event)
            %Assign new default value for NA to default row when
            %adding beams 
            value = app.DefaultNAEditField.Value;
                app.base_table_row={2,categorical({'lg'}),"[0, 0]","[1, i]",app.DefaultPowerEditField.Value,"[0;0;0]","eye(3)",app.DefaultWavelengthEditField.Value,app.DefaultNAEditField.Value,"1+0*i", false};

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %Change view of draggable plot to reflect a different plane
            if app.XYButton.Value
                app.UIAxes.View = [0,90];  
            elseif app.XZButton.Value
                app.UIAxes.View = [0,0];
            elseif app.YZButton.Value
                app.UIAxes.View = [90,0];
            end
            
        end

        % Button pushed function: RefreshTableBasedOnMovedPositionsButton
        function RefreshTableBasedOnMovedPositionsButtonPushed(app, event)
            %Assign new dragged positions of points to the table offsets
            %for corresponding points 
            for i = 1:height(app.param_table)
                app.param_table.Offset(i) = "["+get(app.Points(i).a, 'XData')+";"+get(app.Points(i).a, 'YData')+";"+get(app.Points(i).a, 'ZData')+"]";
                
            end
            app.UITable.Data= app.param_table;

        end

        % Button pushed function: GenerateBeamVisualissationButton
        function GenerateBeamVisualissationButtonPushed2(app, event)
        %Clear relevant variables 
        clear  netbeam Ei Ep I E H app.netbeam
        % clear Ei Ei2 E E2       
        app.netbeam = [];

        %Assign guage values for visualisation progress 
        app.Gauge_2.Value = 1;
        app.Gauge_4.Value = 1;
    

        %Sum all beams to create a netbeam
        app.netbeam = sum([app.Beams(:).a]);

        %Determine speed/resolution of the generation of visualisations 
        speed = app.SpeedResolutionKnob.ValueIndex./2;
        
        
        % Create the grid of points to be viewed 
        app.nx = round(app.BaseXIncramentsEditField.Value*speed);
        app.ny = round(app.BaseYIncramentsEditField.Value*speed);
        app.nz = round(app.BaseZIncramentsEditField.Value*speed); 
        app.xrange = linspace(-app.XLimitsEditField.Value, app.XLimitsEditField.Value, app.nx);
        app.yrange = linspace(-app.YLimitsEditField.Value, app.YLimitsEditField.Value, app.ny);
        app.zrange = linspace(-app.ZLimitsEditField.Value, app.ZLimitsEditField.Value, app.nz);


        [xx, yy, zz] = meshgrid(app.xrange, app.yrange, 0);
        app.xyz = [xx(:) yy(:) zz(:)];

        [xx, yy, zz] = meshgrid(app.xrange, app.zrange, 0);
        app.xyz2 = [xx(:), zz(:), yy(:)];


        %Assign beam basis of net beam as regular
        app.netbeam.basis = 'regular';
        %Generate electric field components of the net beam across the
        %deisred grid 
        app.E = app.netbeam.emFieldXyz(app.xyz.');
        app.E2 = app.netbeam.emFieldXyz(app.xyz2.');


        % Calculate the net intensity of the E field
        % app.Ei=reshape(sqrt(sum(real(app.E).^2,1)),[app.nx,app.ny]);
        datai = app.netbeam.GetVisualisationData('E2', app.xyz, [], app.E.', []);
        app.Ei = zeros(app.ny, app.nx, length(app.moveable_beams));
        app.Ei = reshape(datai, app.ny, app.nx);

        % Calculate the net intensity of the E field in X-Z plane
        % app.Ei=reshape(sqrt(sum(real(app.E).^2,1)),[app.nx,app.ny]);
        datai2 = app.netbeam.GetVisualisationData('E2', app.xyz2, [], app.E2.', []);
        app.Ei2 = zeros(app.nz, app.nx, length(app.moveable_beams));
        app.Ei2 = reshape(datai2, app.nz, app.nx);

        % Calculate the radiance
        datarad = app.netbeam.GetVisualisationData('irradiance', app.xyz, [], app.E.', []);
        app.I = zeros(app.ny, app.nx, length(app.moveable_beams));
        app.I = reshape(datarad, app.ny, app.nx);
        
        % Calculate the phase
        datap = app.netbeam.GetVisualisationData('Arg(Ez)', app.xyz, [], app.E.', []);
        app.Ep = zeros(app.ny, app.nx, length(app.moveable_beams));
        app.Ep = reshape(datap, app.ny, app.nx);


        % Calculate individual field components 
        if app.IndividualcomponentintensitiesButton.Value

        datax = app.netbeam.GetVisualisationData('Abs(Ex)', app.xyz, [], app.E.', []);
        app.Ex = zeros(app.ny, app.nx, length(app.moveable_beams));
        app.Ex = reshape(datax, app.ny, app.nx);
        app.Exc = zeros(size(app.Ei,1),size(app.Ei,2),3); zeros(size(app.E,1),size(app.E,2),3);
        app.Exc(:,:,1) = app.Ex;


        datay = app.netbeam.GetVisualisationData('Abs(Ey)', app.xyz, [], app.E.', []);
        app.Ey = zeros(app.ny, app.nx, length(app.moveable_beams));
        app.Ey = reshape(datay, app.ny, app.nx);
        app.Eyc = zeros(size(app.Ei,1),size(app.Ei,2),3); zeros(size(app.E,1),size(app.E,2),3);
        app.Eyc(:,:,2) = app.Ey;

        dataz = app.netbeam.GetVisualisationData('Abs(Ez)', app.xyz, [], app.E.', []);
        app.Ez = zeros(app.ny, app.nx, length(app.moveable_beams));
        app.Ez = reshape(dataz, app.ny, app.nx);
        app.Ezc = zeros(size(app.Ei,1),size(app.Ei,2),3); zeros(size(app.E,1),size(app.E,2),3);
        app.Ezc(:,:,3) = app.Ez;

        %Determine max electric field strength of any of the components 
        maxval = max(app.Ex+app.Ey+app.Ez,[],"all");
        %Arbitrary scaling factor to brighten figures R-G-B values  
        colour_sca = 3;
        %Scale values according to maximum value and scaling factor   
        app.Exc(:,:,1) = colour_sca*app.Ex./maxval;        app.Eyc(:,:,2) = colour_sca*app.Ey./maxval;        app.Ezc(:,:,3) = colour_sca*app.Ez./maxval;

        end

        % % datas = (app.netbeam.GetVisualisationData('Arg(Ex)', app.xyz, [], app.E.', []).*app.netbeam.GetVisualisationData('Abs(Ex)', app.xyz, [], app.E.', []).^2 +app.netbeam.GetVisualisationData('Arg(Ey)', app.xyz, [], app.E.', []).*app.netbeam.GetVisualisationData('Abs(Ey)', app.xyz, [], app.E.', []).^2)./(app.netbeam.GetVisualisationData('Abs(Ex)', app.xyz, [], app.E.', []).^2+app.netbeam.GetVisualisationData('Abs(Ey)', app.xyz, [], app.E.', []).^2);
        % % app.Spec = zeros(app.ny, app.nx, length(app.moveable_beams));
        % % app.Spec = reshape(datas, app.ny, app.nx);
        % 
        % % datasum = app.netbeam.GetVisualisationData('Abs(Ez)', app.xyz, [], app.E.', []);
        % % app.Esum = zeros(app.ny, app.nx, length(app.moveable_beams));
        % % app.Esum = reshape(datasum, app.ny, app.nx);
%
        % %Calculate the x component of the intensity of the E field
        % app.Ex = zeros(size(app.Ei,1),size(app.Ei,2),3); zeros(size(app.E,1),size(app.E,2),3);
        % app.Ex(:,:,1) = reshape(((abs(app.E(1,:)).^2)),[app.nx,app.ny]);
        % 
        % %Calculate the y component of the intensity of the E field
        % app.Ey = zeros(size(app.Ei,1),size(app.Ei,2),3); zeros(size(app.E,1),size(app.E,2),3);
        % app.Ey(:,:,2) = reshape(((abs(app.E(2,:)).^2)),[app.nx,app.ny]);
        % 
        % %Calculate the z component of the intensity of the E field
        % app.Ez = zeros(size(app.Ei,1),size(app.Ei,2),3); zeros(size(app.E,1),size(app.E,2),3);
        % app.Ez(:,:,3) = reshape(((abs(app.E(3,:)).^2)),[app.nx,app.ny]);
        % 
        % %Determine max electric field strength of any of the components 
        % maxval = max(app.Ex+app.Ey+app.Ez,[],"all");
        % 
        % %Arbitrary scaling factor to brighten figures R-G-B values  
        % colour_sca = 3;
        % %Scale values according to maximum value and scaling factor   
        % app.Ex = colour_sca*app.Ex./maxval;        app.Ey = colour_sca*app.Ey./maxval;        app.Ez = colour_sca*app.Ez./maxval;
        % 
% 
        % % Calculate the phase at the focal plane
        % app.Ep = reshape(angle(app.E(3, :)),[app.nx,app.ny]);
        % 
        % % Calculate the radiance
        % app.I=reshape(sum(abs(app.E).^2,1),[app.nx,app.ny]);
        % 
        % % Special plot attempting to identify the polarisation 
        % app.Spec = zeros(size(app.Ei,1),size(app.Ei,2),3); zeros(size(app.E,1),size(app.E,2),3);
        % app.Spec(:,:,1) = reshape( ( (angle(app.E(1,:)).*(abs(app.E(1,:)).^2)) + (angle(app.E(2,:)).*(abs(app.E(2,:)).^2)) )./((abs(app.E(1,:)).^2).*(abs(app.E(2,:)).^2)) ,[app.nx,app.ny]);
        % 


        % Generate figues on tab 1 for XY and YZ plane electric field intensities 
            % clear app.Ei app.Ei2 app.E app.E2       
            cla(app.UIAxes_4, 'reset')
            cla(app.UIAxes_5, 'reset')
        %XY Plot
        if app.IntensityXYButton.Value
            imagesc(app.UIAxes_4, app.xrange, app.yrange, (app.Ei));
            % axis(app.UIAxes_4,'square');
            app.UIAxes_4.YDir = 'normal';
            title(app.UIAxes_4,'E field intensity X-Y plane');
            xlabel(app.UIAxes_4,'X [\lambda_0]'); ylabel(app.UIAxes_4,'Y [\lambda_0]');
            xlim(app.UIAxes_4, [-app.XLimitsEditField.Value, app.XLimitsEditField.Value]);
            ylim(app.UIAxes_4, [-app.YLimitsEditField.Value, app.YLimitsEditField.Value]);
        end

        %XZ Plot
        if app.IntensityXZButton.Value

            imagesc(app.UIAxes_5, app.xrange, app.zrange, app.Ei2)
        %     axis(app.UIAxes_5,'square');
            app.UIAxes_5.YDir = 'normal';
            title(app.UIAxes_5,'E field intensity X-Z plane');
            xlabel(app.UIAxes_5,'X [\lambda_0]'); ylabel(app.UIAxes_5,'Z [\lambda_0]');
            xlim(app.UIAxes_5, [-app.XLimitsEditField.Value, app.XLimitsEditField.Value]);
            ylim(app.UIAxes_5, [-app.ZLimitsEditField.Value, app.ZLimitsEditField.Value]);
        end


        app.Gauge_2.Value = 50;
        app.Gauge_4.Value = 50;
        %Start function to produce all further non interactive
        %visualisations 
        VisTiles(app)

        %If interactive plot check box enabled then begin to initialize
        %interactive plots 
        if app.DefaultInteractivePlotCheckBox.Value
            dragfig(app)
            Initialize_drag_fig(app)
        end

        app.Gauge_2.Value = 100;
        app.Gauge_4.Value = 100;

        end

        % Drop down opening function: LoadatablefromworkspaceDropDown
        function LoadatablefromworkspaceDropDownOpening(app, event)
        %Go to function to refresh drop down list for tables from work
        %space 
        app.RefreshDropDownLists()
        end

        % Button pushed function: LoadTableButton
        function LoadTableButtonPushed(app, event)
            % Get table from workspace 

            %If no valid option express error 
            if isempty(app.LoadatablefromworkspaceDropDown.Value)
                error('Must supply a table object');
            end

            %load table from the workspace  
            app.param_table = evalin('base', app.LoadatablefromworkspaceDropDown.Value);
            %update app table  
            app.UITable.Data= app.param_table;
            %update beam spinner value   
            app.BeamNumberSpinner.Value = height(app.param_table);
        end

        % Value changed function: DefaultInteractivePlotCheckBox
        function DefaultInteractivePlotCheckBoxValueChanged(app, event)
            %If default interactive checkbox switched off turn off buttons for interaction in second tab
            %If switched on turn on the interactive button in second tab
            %for the XY plot 
            if app.DefaultInteractivePlotCheckBox.Value
                app.RealTimeXYUpdatingCheckBox.Value = 1;
            else
                DisableallrealtimeButtonPushed(app, event);
            end
        end

        % Button pushed function: RegenerateVisualisationsButton
        function RegenerateVisualisationsButtonPushed(app, event)
            %Run visualisation generation 
            GenerateBeamVisualissationButtonPushed2(app, event)
        end

        % Button pushed function: RegenerateBeamsButton
        function RegenerateBeamsButtonPushed(app, event)
            %Run beam generation 
            GenerateBeamsButtonPushed(app, event)
        end

        % Button pushed function: DisableallrealtimeButton
        function DisableallrealtimeButtonPushed(app, event)
            %Switch off all buttons indicating instances of active beam interaction  
            app.DefaultInteractivePlotCheckBox.Value = 0;
            app.RealTimeXYUpdatingCheckBox.Value = 0;
            app.RealTimeXZUpdatingCheckBox.Value = 0;
            %Switch off all instances of active beam interaction, disabling draggability   
            if isfield(app.Points, 'a')
                for i = 1:height(app.param_table)
                    draggable_3d(app.Points(i).a, 'off')
                end
            end
        end

        % Value changed function: RealTimeXYUpdatingCheckBox
        function RealTimeXYUpdatingCheckBoxValueChanged(app, event)
            %Switch on dragability for XY fig   
            if app.RealTimeXYUpdatingCheckBox.Value 
                %Switch on interactive checkbox in tab 1 if necessary   
                app.DefaultInteractivePlotCheckBox.Value = 1;
             
                dragfig(app)
                Initialize_drag_fig(app)

                %Switch off interactive checkbox in tab 1 if necessary   
            elseif and(app.RealTimeXYUpdatingCheckBox.Value == 0,app.RealTimeXZUpdatingCheckBox.Value == 0)
                app.DefaultInteractivePlotCheckBox.Value = 0;
            end

            %%If both interactive buttons switched off disable draggable points interactability  
            if app.RealTimeXYUpdatingCheckBox.Value == 0 && app.RealTimeXZUpdatingCheckBox.Value == 0 && isfield(app.Points, 'a')
                for i = 1:height(app.param_table)
                    draggable_3d(app.Points(i).a, 'off')
                end
            end


        end

        % Value changed function: RealTimeXZUpdatingCheckBox
        function RealTimeXZUpdatingCheckBoxValueChanged(app, event)
            %Same as in the case of RealTimeXYUpdatingCheckBoxValueChanged(app, event)

            if app.RealTimeXZUpdatingCheckBox.Value
                app.DefaultInteractivePlotCheckBox.Value = 1;
            elseif and(app.RealTimeXYUpdatingCheckBox.Value == 0,app.RealTimeXZUpdatingCheckBox.Value == 0)
                app.DefaultInteractivePlotCheckBox.Value = 0;
            end

            if app.RealTimeXYUpdatingCheckBox.Value == 0 && app.RealTimeXZUpdatingCheckBox.Value == 0 && isfield(app.Points, 'a')
                for i = 1:height(app.param_table)
                    draggable_3d(app.Points(i).a, 'off')
                end
            end
        end

        % Button pushed function: SaveBeamButton
        function SaveBeamButtonPushed(app, event)
            % Save the beam to the current matlab workspace
            
            % Check valid beam
            if isempty(app.netbeam)
                return;   
            end
            
            % Get beam variable name
            name = app.VariablenameforsavingbeamtoworkspaceEditField.Value;
            if isempty(name)
                return;  
            end
            
            % Check the name is valid
            if ~isvarname(name)
                error('Invalid variable name in ''Variable Name'' field');
            end
            
            % Write net beam variable to workspace 
            assignin('base', name, app.netbeam);

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BeamGenerationTable = uitab(app.TabGroup);
            app.BeamGenerationTable.Title = 'Beam Generation Table';
            app.UIAxes_4 = uiaxes(app.BeamGenerationTable);
            title(app.UIAxes_4, 'X-Y')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.BeamGenerationTable);
            title(app.UIAxes_5, 'X-Z')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Z')
            app.UITable = uitable(app.BeamGenerationTable);
            app.UITable.ColumnName = {'Beam'; 'Type'; 'Mode'; 'Offset'; 'Polarisation'; 'Power'; 'Wavelength'; 'NA'; 'Phase'; 'Clone Row'; 'Delete Row'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.BaseOptionsPanel = uipanel(app.BeamGenerationTable);
            app.BaseOptionsPanel.Title = 'Base Options';
            app.BeamNumberSpinnerLabel = uilabel(app.BaseOptionsPanel);
            app.BeamNumberSpinnerLabel.Text = 'Beam Number';
            app.BeamNumberSpinner = uispinner(app.BaseOptionsPanel);
            app.BeamNumberSpinner.Limits = [1 25];
            app.BeamNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @BeamNumberSpinnerValueChanged, true);
            app.BeamNumberSpinner.Value = 1;
            app.DefaultWavelengthEditFieldLabel = uilabel(app.BaseOptionsPanel);
            app.DefaultWavelengthEditFieldLabel.Text = 'Default Wavelength';
            app.DefaultWavelengthEditField = uieditfield(app.BaseOptionsPanel, 'numeric');
            app.DefaultWavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @DefaultWavelengthEditFieldValueChanged, true);
            app.DefaultWavelengthEditField.Value = 1;
            app.DefaultNAEditFieldLabel = uilabel(app.BaseOptionsPanel);
            app.DefaultNAEditFieldLabel.Text = 'Default NA';
            app.DefaultNAEditField = uieditfield(app.BaseOptionsPanel, 'numeric');
            app.DefaultNAEditField.ValueChangedFcn = createCallbackFcn(app, @DefaultNAEditFieldValueChanged, true);
            app.DefaultNAEditField.Value = 1.02;
            app.DefaultPowerEditFieldLabel = uilabel(app.BaseOptionsPanel);
            app.DefaultPowerEditFieldLabel.Text = 'Default Power';
            app.DefaultPowerEditField = uieditfield(app.BaseOptionsPanel, 'numeric');
            app.DefaultPowerEditField.ValueChangedFcn = createCallbackFcn(app, @DefaultPowerEditFieldValueChanged, true);
            app.DefaultPowerEditField.Value = 0.05;
            app.IndexMediumEditFieldLabel = uilabel(app.BaseOptionsPanel);
            app.IndexMediumEditFieldLabel.Text = 'Index Medium';
            app.IndexMediumEditField = uieditfield(app.BaseOptionsPanel, 'numeric');
            app.IndexMediumEditField.Value = 1.33;
            app.NMaxCapEditFieldLabel = uilabel(app.BaseOptionsPanel);
            app.NMaxCapEditFieldLabel.Text = 'NMax Cap';
            app.NMaxCapEditField = uieditfield(app.BaseOptionsPanel, 'numeric');
            app.NMaxCapEditField.Value = 23;
            app.UseCapCheckBox = uicheckbox(app.BaseOptionsPanel);
            app.UseCapCheckBox.Text = 'Use Cap';
            app.UseCapCheckBox.Value = true;
            app.Label_2 = uilabel(app.BeamGenerationTable);
            app.Label_2.Text = '';
            app.TextArea = uitextarea(app.BeamGenerationTable);
            app.TextArea.Value = {'3D Multi Beam Visualiser for OTT'};
            app.Gauge_2 = uigauge(app.BeamGenerationTable, 'linear');
            app.GenerateBeamsButton = uibutton(app.BeamGenerationTable, 'push');
            app.GenerateBeamsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBeamsButtonPushed, true);
            app.GenerateBeamsButton.Text = 'Generate Beams';
            app.GenerateBeamVisualissationButton = uibutton(app.BeamGenerationTable, 'push');
            app.GenerateBeamVisualissationButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBeamVisualissationButtonPushed2, true);
            app.GenerateBeamVisualissationButton.Text = 'Generate Beam Visualissation';
            app.Gauge = uigauge(app.BeamGenerationTable, 'linear');
            app.ActiveVisuallisationsInAllVisualisationsPanel = uipanel(app.BeamGenerationTable);
            app.ActiveVisuallisationsInAllVisualisationsPanel.Title = 'Active Visuallisations In All Visualisations Panel';
            app.RadianceButton = uibutton(app.ActiveVisuallisationsInAllVisualisationsPanel, 'state');
            app.RadianceButton.Text = 'Radiance';
            app.RadianceButton.Value = true;
            app.IndividualcomponentintensitiesButton = uibutton(app.ActiveVisuallisationsInAllVisualisationsPanel, 'state');
            app.IndividualcomponentintensitiesButton.Text = 'Individual component intensities';
            app.SumofcomponentintensitiesButton = uibutton(app.ActiveVisuallisationsInAllVisualisationsPanel, 'state');
            app.SumofcomponentintensitiesButton.Text = 'Sum of component intensities';
            app.PhaseButton = uibutton(app.ActiveVisuallisationsInAllVisualisationsPanel, 'state');
            app.PhaseButton.Text = 'Phase';
            app.PhaseButton.Value = true;
            app.IntensityXYButton = uibutton(app.ActiveVisuallisationsInAllVisualisationsPanel, 'state');
            app.IntensityXYButton.Text = 'Intensity X-Y';
            app.IntensityXYButton.Value = true;
            app.IntensityXZButton = uibutton(app.ActiveVisuallisationsInAllVisualisationsPanel, 'state');
            app.IntensityXZButton.Text = 'Intensity X-Z';
            app.IntensityXZButton.Value = true;
            app.VariablenameforsavingtabletoworkspaceEditFieldLabel = uilabel(app.BeamGenerationTable);
            app.VariablenameforsavingtabletoworkspaceEditFieldLabel.Text = 'Variable name for saving table to workspace';
            app.VariablenameforsavingtabletoworkspaceEditField = uieditfield(app.BeamGenerationTable, 'text');
            app.VariablenameforsavingtabletoworkspaceEditField.Value = 'Beam_Table';
            app.SaveTableButton = uibutton(app.BeamGenerationTable, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save Table';
            app.LoadatablefromworkspaceDropDownLabel = uilabel(app.BeamGenerationTable);
            app.LoadatablefromworkspaceDropDownLabel.Text = 'Load a table from workspace';
            app.LoadatablefromworkspaceDropDown = uidropdown(app.BeamGenerationTable);
            app.LoadatablefromworkspaceDropDown.Items = {};
            app.LoadatablefromworkspaceDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LoadatablefromworkspaceDropDownOpening, true);
            app.LoadatablefromworkspaceDropDown.Value = {};
            app.LoadTableButton = uibutton(app.BeamGenerationTable, 'push');
            app.LoadTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButtonPushed, true);
            app.LoadTableButton.Text = 'Load Table';
            app.VisualisationOptionsPanel = uipanel(app.BeamGenerationTable);
            app.VisualisationOptionsPanel.Title = 'Visualisation Options';
            app.XLimitsEditFieldLabel = uilabel(app.VisualisationOptionsPanel);
            app.XLimitsEditFieldLabel.Text = 'X Limits';
            app.XLimitsEditField = uieditfield(app.VisualisationOptionsPanel, 'numeric');
            app.XLimitsEditField.Value = 3;
            app.BaseXIncramentsEditFieldLabel = uilabel(app.VisualisationOptionsPanel);
            app.BaseXIncramentsEditFieldLabel.Text = 'Base X Incraments';
            app.BaseXIncramentsEditField = uieditfield(app.VisualisationOptionsPanel, 'numeric');
            app.BaseXIncramentsEditField.Value = 200;
            app.BaseYIncramentsLabel = uilabel(app.VisualisationOptionsPanel);
            app.BaseYIncramentsLabel.Text = 'Base Y Incraments';
            app.BaseYIncramentsEditField = uieditfield(app.VisualisationOptionsPanel, 'numeric');
            app.BaseYIncramentsEditField.Value = 200;
            app.YLimitsEditFieldLabel = uilabel(app.VisualisationOptionsPanel);
            app.YLimitsEditFieldLabel.Text = 'Y Limits';
            app.YLimitsEditField = uieditfield(app.VisualisationOptionsPanel, 'numeric');
            app.YLimitsEditField.Value = 3;
            app.ZLimitsLabel = uilabel(app.VisualisationOptionsPanel);
            app.ZLimitsLabel.Text = 'Z Limits';
            app.ZLimitsEditField = uieditfield(app.VisualisationOptionsPanel, 'numeric');
            app.ZLimitsEditField.Value = 3;
            app.BaseZIncramentsLabel = uilabel(app.VisualisationOptionsPanel);
            app.BaseZIncramentsLabel.Text = 'Base Z Incraments';
            app.BaseZIncramentsEditField = uieditfield(app.VisualisationOptionsPanel, 'numeric');
            app.BaseZIncramentsEditField.Value = 200;
            app.DefaultInteractivePlotCheckBox = uicheckbox(app.BeamGenerationTable);
            app.DefaultInteractivePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultInteractivePlotCheckBoxValueChanged, true);
            app.DefaultInteractivePlotCheckBox.Text = 'Default Interactive Plot';
            app.DefaultInteractivePlotCheckBox.Value = true;
            app.SpeedResolutionKnobLabel = uilabel(app.BeamGenerationTable);
            app.SpeedResolutionKnobLabel.Text = 'Speed \ Resolution';
            app.SpeedResolutionKnob = uiknob(app.BeamGenerationTable, 'discrete');
            app.SpeedResolutionKnob.Items = {'Highest Speed ', 'High Speed', 'High Resolution', 'Highest Resolution '};
            app.SpeedResolutionKnob.Value = 'Highest Speed ';
            app.VariablenameforsavingbeamtoworkspaceEditFieldLabel = uilabel(app.BeamGenerationTable);
            app.VariablenameforsavingbeamtoworkspaceEditFieldLabel.Text = 'Variable name for saving beam to workspace';
            app.VariablenameforsavingbeamtoworkspaceEditField = uieditfield(app.BeamGenerationTable, 'text');
            app.VariablenameforsavingbeamtoworkspaceEditField.Value = 'Beam';
            app.SaveBeamButton = uibutton(app.BeamGenerationTable, 'push');
            app.SaveBeamButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBeamButtonPushed, true);
            app.SaveBeamButton.Text = 'Save Beam';
            app.InteractiveFigureTab = uitab(app.TabGroup);
            app.InteractiveFigureTab.Title = 'Interactive  Figure';
            app.UIAxes = uiaxes(app.InteractiveFigureTab);
            title(app.UIAxes, 'Real Time Draggable')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.InteractiveFigureTab);
            title(app.UIAxes_2, 'Positions')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.InteractiveFigureTab);
            title(app.UIAxes_3, 'Output')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_7 = uiaxes(app.InteractiveFigureTab);
            title(app.UIAxes_7, 'Output')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.ButtonGroup = uibuttongroup(app.InteractiveFigureTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.XYButton = uitogglebutton(app.ButtonGroup);
            app.XYButton.Text = 'X-Y';
            app.XYButton.Value = true;
            app.XZButton = uitogglebutton(app.ButtonGroup);
            app.XZButton.Text = 'X-Z';
            app.YZButton = uitogglebutton(app.ButtonGroup);
            app.YZButton.Text = 'Y-Z';
            app.RealTimeXYUpdatingCheckBox = uicheckbox(app.InteractiveFigureTab);
            app.RealTimeXYUpdatingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealTimeXYUpdatingCheckBoxValueChanged, true);
            app.RealTimeXYUpdatingCheckBox.Text = 'Real Time X-Y Updating ';
            app.RealTimeXYUpdatingCheckBox.Value = true;
            app.RefreshTableBasedOnMovedPositionsButton = uibutton(app.InteractiveFigureTab, 'push');
            app.RefreshTableBasedOnMovedPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshTableBasedOnMovedPositionsButtonPushed, true);
            app.RefreshTableBasedOnMovedPositionsButton.Text = 'Refresh Table Based On Moved Positions';
            app.RegenerateBeamsButton = uibutton(app.InteractiveFigureTab, 'push');
            app.RegenerateBeamsButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateBeamsButtonPushed, true);
            app.RegenerateBeamsButton.Text = 'Regenerate Beams';
            app.RegenerateVisualisationsButton = uibutton(app.InteractiveFigureTab, 'push');
            app.RegenerateVisualisationsButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateVisualisationsButtonPushed, true);
            app.RegenerateVisualisationsButton.Text = 'Regenerate Visualisations';
            app.Gauge_3 = uigauge(app.InteractiveFigureTab, 'linear');
            app.Gauge_4 = uigauge(app.InteractiveFigureTab, 'linear');
            app.RealTimeXZUpdatingCheckBox = uicheckbox(app.InteractiveFigureTab);
            app.RealTimeXZUpdatingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealTimeXZUpdatingCheckBoxValueChanged, true);
            app.RealTimeXZUpdatingCheckBox.Text = 'Real Time X-Z Updating ';
            app.DisableallrealtimeButton = uibutton(app.InteractiveFigureTab, 'push');
            app.DisableallrealtimeButton.ButtonPushedFcn = createCallbackFcn(app, @DisableallrealtimeButtonPushed, true);
            app.DisableallrealtimeButton.Text = 'Disable all  real-time';
            app.AllVisuallisationsTab = uitab(app.TabGroup);
            app.AllVisuallisationsTab.Title = 'All Visuallisations';
            app.Panel = uipanel(app.AllVisuallisationsTab);
            app.updatetableButton = uibutton(app.UIFigure, 'push');
            app.updatetableButton.ButtonPushedFcn = createCallbackFcn(app, @updatetableButtonPushed, true);
            app.updatetableButton.Text = 'update table';
            app.SpecialButton = uibutton(app.UIFigure, 'state');
            app.SpecialButton.Text = 'Special';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Light_Field_Generator_3D_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @TableStrt)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end